classdef GUI < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                    matlab.ui.Figure
        DemonstrationMixChemButton  matlab.ui.control.Button
        Button_2                    matlab.ui.control.Button
        TabGroup                    matlab.ui.container.TabGroup
        STARTTABTab                 matlab.ui.container.Tab
        UR3TeachTab                 matlab.ui.container.Tab
        Joint6Slider_UR3            matlab.ui.control.Slider
        Joint6SliderLabel_2         matlab.ui.control.Label
        Joint4Slider_UR3            matlab.ui.control.Slider
        Joint4SliderLabel_2         matlab.ui.control.Label
        Joint5Slider_UR3            matlab.ui.control.Slider
        Joint5SliderLabel_2         matlab.ui.control.Label
        Joint3Slider_UR3            matlab.ui.control.Slider
        Joint3SliderLabel_2         matlab.ui.control.Label
        Joint2Slider_UR3            matlab.ui.control.Slider
        Joint2SliderLabel_2         matlab.ui.control.Label
        Joint1Slider_UR3            matlab.ui.control.Slider
        Joint1SliderLabel_2         matlab.ui.control.Label
        EditField__UR3Joint5        matlab.ui.control.NumericEditField
        EditField__UR3Joint6        matlab.ui.control.NumericEditField
        EditField__UR3Joint4        matlab.ui.control.NumericEditField
        EditField__UR3Joint3        matlab.ui.control.NumericEditField
        EditField__UR3Joint2        matlab.ui.control.NumericEditField
        EditField_UR3Joint1         matlab.ui.control.NumericEditField
        UR3XYZTab                   matlab.ui.container.Tab
        Joint6Slider_UR3_3          matlab.ui.control.Slider
        ZRotationLabel              matlab.ui.control.Label
        Joint4Slider_UR3_3          matlab.ui.control.Slider
        XRotationLabel              matlab.ui.control.Label
        Joint5Slider_UR3_3          matlab.ui.control.Slider
        YRotationLabel              matlab.ui.control.Label
        Joint3Slider_UR3_3          matlab.ui.control.Slider
        ZTranslationLabel           matlab.ui.control.Label
        Joint2Slider_UR3_3          matlab.ui.control.Slider
        YTranslationLabel           matlab.ui.control.Label
        Joint1Slider_UR3_3          matlab.ui.control.Slider
        XTranslationLabel           matlab.ui.control.Label
        EditField__UR3Joint5_3      matlab.ui.control.NumericEditField
        EditField__UR3Joint6_3      matlab.ui.control.NumericEditField
        EditField__UR3Joint4_3      matlab.ui.control.NumericEditField
        EditField__UR3Joint3_3      matlab.ui.control.NumericEditField
        EditField__UR3Joint2_3      matlab.ui.control.NumericEditField
        EditField_UR3Joint1_3       matlab.ui.control.NumericEditField
        CustomBotjointTab           matlab.ui.container.Tab
        EditField_LabBotJoint1      matlab.ui.control.NumericEditField
        Joint1Slider_LabBot         matlab.ui.control.Slider
        EditField__LabBotJoint2     matlab.ui.control.NumericEditField
        Joint2Slider_LabBot         matlab.ui.control.Slider
        EditField__LabBotJoint3     matlab.ui.control.NumericEditField
        Joint3Slider_LabBot         matlab.ui.control.Slider
        EditField__LabBotJoint4     matlab.ui.control.NumericEditField
        Joint4Slider_LabBot         matlab.ui.control.Slider
        EditField__LabBotJoint5     matlab.ui.control.NumericEditField
        Joint5Slider_LabBot         matlab.ui.control.Slider
        EditField__LabBotJoint6     matlab.ui.control.NumericEditField
        Joint6Slider_LabBot         matlab.ui.control.Slider
        EditField__LabBotJoint7     matlab.ui.control.NumericEditField
        Joint7Slider_LabBot         matlab.ui.control.Slider
        Joint7SliderLabel           matlab.ui.control.Label
        Joint6SliderLabel           matlab.ui.control.Label
        Joint4SliderLabel           matlab.ui.control.Label
        Joint5SliderLabel           matlab.ui.control.Label
        Joint3SliderLabel           matlab.ui.control.Label
        Joint2SliderLabel           matlab.ui.control.Label
        Joint1SliderLabel           matlab.ui.control.Label
        CustomBotXYZTab             matlab.ui.container.Tab
        Joint6Slider_UR3_4          matlab.ui.control.Slider
        ZRotationLabel_2            matlab.ui.control.Label
        Joint4Slider_UR3_4          matlab.ui.control.Slider
        XRotationLabel_2            matlab.ui.control.Label
        Joint5Slider_UR3_4          matlab.ui.control.Slider
        YRotationLabel_2            matlab.ui.control.Label
        Joint3Slider_UR3_4          matlab.ui.control.Slider
        ZTranslationLabel_2         matlab.ui.control.Label
        Joint2Slider_UR3_4          matlab.ui.control.Slider
        YTranslationLabel_2         matlab.ui.control.Label
        Joint1Slider_UR3_4          matlab.ui.control.Slider
        XTranslationLabel_2         matlab.ui.control.Label
        EditField__UR3Joint5_4      matlab.ui.control.NumericEditField
        EditField__UR3Joint6_4      matlab.ui.control.NumericEditField
        EditField__UR3Joint4_4      matlab.ui.control.NumericEditField
        EditField__UR3Joint3_4      matlab.ui.control.NumericEditField
        EditField__UR3Joint2_4      matlab.ui.control.NumericEditField
        EditField_UR3Joint1_4       matlab.ui.control.NumericEditField
        MomentaryButton             slrealtime.ui.control.MomentaryButton
        Switch                      matlab.ui.control.RockerSwitch
        SwitchLabel                 matlab.ui.control.Label
        ArmBStatusLamp              matlab.ui.control.Lamp
        ArmBStatusLampLabel         matlab.ui.control.Label
        ArmAStatusLamp              matlab.ui.control.Lamp
        ArmAStatusLampLabel         matlab.ui.control.Label
        ChemicalsListBox            matlab.ui.control.ListBox
        ChemicalsListBoxLabel       matlab.ui.control.Label
    end

    
    properties (Access = public)
        mainControl
        GUI_Func
        autoUpdate
        myTimer % Description
        ButtonListener
        rUR3
        rCustomBot
        controlCase
        
        UR3J1
        UR3J2
        UR3J3
        UR3J4
        UR3J5
        UR3J6

        CustomBotJ1
        CustomBotJ2
        CustomBotJ3
        CustomBotJ4
        CustomBotJ5
        CustomBotJ6
        CustomBotJ7 

        XTranslationUR3
        YTranslationUR3
        ZTranslationUR3
        XRotationUR3
        YRotationUR3
        ZRotationUR3

        XTranslationCB
        YTranslationCB
        ZTranslationCB
        XRotationCB
        YRotationCB
        ZRotationCB

        GUI_C


    end

    % methods (Access = protected)
        
   
    methods (Access = private)
        
        function updateJoy(app,~,~)
            wrench = zeros(6,1);
            wrench(1) = app.UR3J1; 
            wrench(2) = app.UR3J2;
            wrench(3) = app.UR3J3;
            wrench(4) = app.UR3J4;
            wrench(5) = app.UR3J5;
            wrench(6) = app.UR3J6;
            
            assignin('base','wrench',wrench);

            watch = zeros(7,1);
            watch(1) = app.CustomBotJ1; 
            watch(2) = app.CustomBotJ2;
            watch(3) = app.CustomBotJ3;
            watch(4) = app.CustomBotJ4;
            watch(5) = app.CustomBotJ5;
            watch(6) = app.CustomBotJ6;
            watch(7) = app.CustomBotJ7;

            assignin('base','watch',watch);

            rench = zeros(6,1);
            rench(1) = app.XTranslationUR3;
            rench(2) = app.YTranslationUR3;
            rench(3) = app.ZTranslationUR3;
            rench(4) = app.XRotationUR3;
            rench(5) = app.YRotationUR3;
            rench(6) = app.ZRotationUR3;

            assignin('base','rench',rench);
    
            atch = zeros(6,1);
            atch(1) = app.XTranslationCB;
            atch(2) = app.YTranslationCB;
            atch(3) = app.ZTranslationCB;
            atch(4) = app.XRotationCB;
            atch(5) = app.YRotationCB;
            atch(6) = app.ZRotationCB;

            assignin('base','atch',atch);

            GUI_Case = app.GUI_C;

            assignin('base','GUI_Case',GUI_Case);

            %evalin('base','who');
            %wrench = [app.vx; app.vy; app.vz; app.wx; app.wy; app.wz];
            %disp(wrench);
        end


    end
    
    methods (Access = public)
               
        function wrench = read1(app)
            wrench = zeros(6,1);
            wrench(1) = app.UR3J1; 
            wrench(2) = app.UR3J2;
            wrench(3) = app.UR3J3;
            wrench(4) = app.UR3J4;
            wrench(5) = app.UR3J5;
            wrench(6) = app.UR3J6;
        end

        function watch = read2(app)
            watch = zeros(6,1);
            watch(1) = app.CustomBotJ1; 
            watch(2) = app.CustomBotJ2;
            watch(3) = app.CustomBotJ3;
            watch(4) = app.CustomBotJ4;
            watch(5) = app.CustomBotJ5;
            watch(6) = app.CustomBotJ6;
            watch(7) = app.CustomBotJ7;
        end

        function rench = read3(app)
            rench = zeros(6,1);
            rench(1) = app.XTranslationUR3;
            rench(2) = app.YTranslationUR3;
            rench(3) = app.ZTranslationUR3;
            rench(4) = app.XRotationUR3;
            rench(5) = app.YRotationUR3;
            rench(6) = app.ZRotationUR3;
        end

        function atch = read4(app)
            atch = zeros(6,1);
            atch(1) = app.XTranslationCB;
            atch(2) = app.YTranslationCB;
            atch(3) = app.ZTranslationCB;
            atch(4) = app.XRotationCB;
            atch(5) = app.YRotationCB;
            atch(6) = app.ZRotationCB;
        end

        function GUI_Case = read5(app)
            GUI_Case = app.GUI_C;
        end


    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.UR3J1 = 0;
            app.UR3J2 = 0;
            app.UR3J3 = 0;
            app.UR3J4 = 0;
            app.UR3J5 = 0;
            app.UR3J6 = 0;

            app.CustomBotJ1 = 0;
            app.CustomBotJ2 = 0;
            app.CustomBotJ3 = 0;
            app.CustomBotJ4 = 0;
            app.CustomBotJ5 = 0;
            app.CustomBotJ6 = 0;
            app.CustomBotJ7 = 0;

            app.XTranslationUR3 = 0;
            app.YTranslationUR3 = 0;
            app.ZTranslationUR3 = 0;
            app.XRotationUR3 = 0;
            app.YRotationUR3 = 0;
            app.ZRotationUR3 = 0;
    
            app.XTranslationCB = 0;
            app.YTranslationCB = 0;
            app.ZTranslationCB = 0;
            app.XRotationCB = 0;
            app.YRotationCB = 0;
            app.ZRotationCB = 0;

            app.GUI_C = 5;

            % app.mainControl = LabBotMainControl(0);        

            app.myTimer = timer('Period',0.05,...
                'ExecutionMode', 'fixedSpacing', ...
                'TasksToExecute', Inf);

            app.myTimer.TimerFcn = @(~,~)app.updateJoy;
            
            %start(app.myTimer);
        end

        % Value changing function: Joint1Slider_UR3
        function Joint1Slider_UR3ValueChanging(app, event)
            % Get the changing value from the event
            app.UR3J1 = event.Value;
            
        end

        % Value changing function: Joint2Slider_UR3
        function Joint2Slider_UR3ValueChanging(app, event)
            app.UR3J2 = event.Value;
            
        end

        % Value changing function: Joint3Slider_UR3
        function Joint3Slider_UR3ValueChanging(app, event)
            app.UR3J3 = event.Value;
            
        end

        % Value changing function: Joint4Slider_UR3
        function Joint4Slider_UR3ValueChanging(app, event)
            app.UR3J4 = event.Value;
            
        end

        % Value changing function: Joint5Slider_UR3
        function Joint5Slider_UR3ValueChanging(app, event)
            app.UR3J5 = event.Value;
            
        end

        % Value changing function: Joint6Slider_UR3
        function Joint6Slider_UR3ValueChanging(app, event)
            app.UR3J6 = event.Value;
            
        end

        % Value changing function: Joint1Slider_LabBot
        function Joint1Slider_LabBotValueChanging(app, event)
            app.CustomBotJ1 = event.Value;
            
        end

        % Value changing function: Joint2Slider_LabBot
        function Joint2Slider_LabBotValueChanging(app, event)
            app.CustomBotJ2 = event.Value;
            
        end

        % Value changing function: Joint3Slider_LabBot
        function Joint3Slider_LabBotValueChanging(app, event)
            app.CustomBotJ3 = event.Value;
            
        end

        % Value changing function: Joint4Slider_LabBot
        function Joint4Slider_LabBotValueChanging(app, event)
            app.CustomBotJ4 = event.Value;
            
        end

        % Value changing function: Joint5Slider_LabBot
        function Joint5Slider_LabBotValueChanging(app, event)
            app.CustomBotJ5 = event.Value;
            
        end

        % Value changing function: Joint6Slider_LabBot
        function Joint6Slider_LabBotValueChanging(app, event)
            app.CustomBotJ6 = event.Value;
            
        end

        % Value changing function: Joint7Slider_LabBot
        function Joint7Slider_LabBotValueChanging(app, event)
            app.CustomBotJ1 = event.Value;
            
        end

        % Button pushed function: Button_2
        function Button_2Pushed(app, event)
            % Directly toggle the E-Stop flag in MainControl
            app.mainControl.isStopped = true;
            disp('E-Stop activated from GUI.');
        
        end

        % Button pushed function: DemonstrationMixChemButton
        function DemonstrationMixChemButtonPushed(app, event)
            app.mainControl = LabBotMainControl(2);
        end

        % Button down function: CustomBotjointTab
        function CustomBotjointTabButtonDown(app, event)
            % app.GUI_C = 2;
            % app.mainControl = obj.mainControl.GUI_InterfaceControl();
        end

        % Button down function: UR3TeachTab
        function UR3TeachTabButtonDown(app, event)
            % app.GUI_C = 1;
            % app.mainControl = obj.mainControl.GUI_InterfaceControl();
        end

        % Value changing function: Joint1Slider_UR3_4
        function Joint1Slider_UR3_4ValueChanging(app, event)
            app.XTranslationCB = event.Value;
            
        end

        % Value changing function: Joint2Slider_UR3_4
        function Joint2Slider_UR3_4ValueChanging(app, event)
            app.YTranslationCB = event.Value;
            
        end

        % Value changing function: Joint3Slider_UR3_4
        function Joint3Slider_UR3_4ValueChanging(app, event)
            app.ZTranslationCB = event.Value;
            
        end

        % Value changing function: Joint4Slider_UR3_4
        function Joint4Slider_UR3_4ValueChanging(app, event)
            app.XRotationCB = event.Value;
            
        end

        % Value changing function: Joint5Slider_UR3_4
        function Joint5Slider_UR3_4ValueChanging(app, event)
            app.YRotationCB = event.Value;
            
        end

        % Value changing function: Joint6Slider_UR3_4
        function Joint6Slider_UR3_4ValueChanging(app, event)
            app.ZRotationCB = event.Value;
            
        end

        % Value changing function: Joint1Slider_UR3_3
        function Joint1Slider_UR3_3ValueChanging(app, event)
            app.XTranslationUR3 = event.Value;
            
        end

        % Value changing function: Joint2Slider_UR3_3
        function Joint2Slider_UR3_3ValueChanging(app, event)
            app.YTranslationUR3 = event.Value;
            
        end

        % Value changing function: Joint3Slider_UR3_3
        function Joint3Slider_UR3_3ValueChanging(app, event)
            app.ZTranslationUR3 = event.Value;
            
            
        end

        % Value changing function: Joint4Slider_UR3_3
        function Joint4Slider_UR3_3ValueChanging(app, event)
            app.XRotationUR3 = event.Value;
            
        end

        % Value changing function: Joint5Slider_UR3_3
        function Joint5Slider_UR3_3ValueChanging(app, event)
            app.YRotationUR3 = event.Value;
            
        end

        % Value changing function: Joint6Slider_UR3_3
        function Joint6Slider_UR3_3ValueChanging(app, event)
            app.ZRotationUR3 = event.Value;
            
        end

        % Button down function: UR3XYZTab
        function UR3XYZTabButtonDown(app, event)
            % app.GUI_C = 3;
            % app.mainControl.GUI_InterfaceControl();
        end

        % Button down function: CustomBotXYZTab
        function CustomBotXYZTabButtonDown(app, event)
            % app.GUI_C = 4;
            % app.mainControl.GUI_InterfaceControl();
        end

        % Button down function: STARTTABTab
        function STARTTABTabButtonDown(app, event)
            % app.GUI_C = 5;
            % app.mainControl.GUI_InterfaceControl();
        end

        % Value changed function: Joint1Slider_UR3_3
        function Joint1Slider_UR3_3ValueChanged(app, event)
            app.Joint1Slider_UR3_3.Value = 0; % reset back to zero
            app.XTranslationUR3 = 0;
        end

        % Value changed function: Joint2Slider_UR3_3
        function Joint2Slider_UR3_3ValueChanged(app, event)
            app.Joint2Slider_UR3_3.Value = 0; % reset back to zero
            app.YTranslationUR3 = 0;
        end

        % Value changed function: Joint3Slider_UR3_3
        function Joint3Slider_UR3_3ValueChanged(app, event)
            app.Joint3Slider_UR3_3.Value = 0; % reset back to zero
            app.ZTranslationUR3 = 0;
        end

        % Value changed function: Joint4Slider_UR3_3
        function Joint4Slider_UR3_3ValueChanged(app, event)
            app.Joint4Slider_UR3_3.Value = 0; % reset back to zero
            app.XRotationUR3 = 0;
        end

        % Value changed function: Joint5Slider_UR3_3
        function Joint5Slider_UR3_3ValueChanged(app, event)
            app.Joint5Slider_UR3_3.Value = 0; % reset back to zero
            app.YRotationUR3= 0;
        end

        % Value changed function: Joint6Slider_UR3_3
        function Joint6Slider_UR3_3ValueChanged(app, event)
            app.Joint6Slider_UR3_3.Value = 0;
            app.YRotationUR3= 0;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.ChemicalsListBoxLabel = uilabel(app.UIFigure);
            app.ChemicalsListBoxLabel.Text = 'Chemicals';
            app.ChemicalsListBox = uilistbox(app.UIFigure);
            app.ChemicalsListBox.Items = {'Water', 'Glucose', 'Sodium Hydroxide', 'Indigo Carmine'};
            app.ChemicalsListBox.Multiselect = 'on';
            app.ChemicalsListBox.Tooltip = {'Ctrl click to select multiple'};
            app.ChemicalsListBox.Value = {'Water'};
            app.ArmAStatusLampLabel = uilabel(app.UIFigure);
            app.ArmAStatusLampLabel.Text = 'Arm A Status';
            app.ArmAStatusLamp = uilamp(app.UIFigure);
            app.ArmBStatusLampLabel = uilabel(app.UIFigure);
            app.ArmBStatusLampLabel.Text = 'Arm B Status';
            app.ArmBStatusLamp = uilamp(app.UIFigure);
            app.SwitchLabel = uilabel(app.UIFigure);
            app.SwitchLabel.Text = 'Switch';
            app.Switch = uiswitch(app.UIFigure, 'rocker');
            app.MomentaryButton = slrealtime.ui.control.MomentaryButton(app.UIFigure);
            app.MomentaryButton.Text = 'MomentaryButton';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.STARTTABTab = uitab(app.TabGroup);
            app.STARTTABTab.Title = 'START TAB';
            app.STARTTABTab.ButtonDownFcn = createCallbackFcn(app, @STARTTABTabButtonDown, true);
            app.UR3TeachTab = uitab(app.TabGroup);
            app.UR3TeachTab.Title = 'UR3 Teach';
            app.UR3TeachTab.ButtonDownFcn = createCallbackFcn(app, @UR3TeachTabButtonDown, true);
            app.EditField_UR3Joint1 = uieditfield(app.UR3TeachTab, 'numeric');
            app.EditField__UR3Joint2 = uieditfield(app.UR3TeachTab, 'numeric');
            app.EditField__UR3Joint3 = uieditfield(app.UR3TeachTab, 'numeric');
            app.EditField__UR3Joint4 = uieditfield(app.UR3TeachTab, 'numeric');
            app.EditField__UR3Joint6 = uieditfield(app.UR3TeachTab, 'numeric');
            app.EditField__UR3Joint5 = uieditfield(app.UR3TeachTab, 'numeric');
            app.Joint1SliderLabel_2 = uilabel(app.UR3TeachTab);
            app.Joint1SliderLabel_2.Text = 'Joint 1';
            app.Joint1Slider_UR3 = uislider(app.UR3TeachTab);
            app.Joint1Slider_UR3.Limits = [-360 360];
            app.Joint1Slider_UR3.ValueChangingFcn = createCallbackFcn(app, @Joint1Slider_UR3ValueChanging, true);
            app.Joint2SliderLabel_2 = uilabel(app.UR3TeachTab);
            app.Joint2SliderLabel_2.Text = 'Joint 2';
            app.Joint2Slider_UR3 = uislider(app.UR3TeachTab);
            app.Joint2Slider_UR3.Limits = [-360 360];
            app.Joint2Slider_UR3.ValueChangingFcn = createCallbackFcn(app, @Joint2Slider_UR3ValueChanging, true);
            app.Joint3SliderLabel_2 = uilabel(app.UR3TeachTab);
            app.Joint3SliderLabel_2.Text = 'Joint 3';
            app.Joint3Slider_UR3 = uislider(app.UR3TeachTab);
            app.Joint3Slider_UR3.Limits = [-360 360];
            app.Joint3Slider_UR3.ValueChangingFcn = createCallbackFcn(app, @Joint3Slider_UR3ValueChanging, true);
            app.Joint5SliderLabel_2 = uilabel(app.UR3TeachTab);
            app.Joint5SliderLabel_2.Text = 'Joint 5';
            app.Joint5Slider_UR3 = uislider(app.UR3TeachTab);
            app.Joint5Slider_UR3.Limits = [-360 360];
            app.Joint5Slider_UR3.ValueChangingFcn = createCallbackFcn(app, @Joint5Slider_UR3ValueChanging, true);
            app.Joint4SliderLabel_2 = uilabel(app.UR3TeachTab);
            app.Joint4SliderLabel_2.Text = 'Joint 4';
            app.Joint4Slider_UR3 = uislider(app.UR3TeachTab);
            app.Joint4Slider_UR3.Limits = [-360 360];
            app.Joint4Slider_UR3.ValueChangingFcn = createCallbackFcn(app, @Joint4Slider_UR3ValueChanging, true);
            app.Joint6SliderLabel_2 = uilabel(app.UR3TeachTab);
            app.Joint6SliderLabel_2.Text = 'Joint 6';
            app.Joint6Slider_UR3 = uislider(app.UR3TeachTab);
            app.Joint6Slider_UR3.Limits = [-360 360];
            app.Joint6Slider_UR3.ValueChangingFcn = createCallbackFcn(app, @Joint6Slider_UR3ValueChanging, true);
            app.UR3XYZTab = uitab(app.TabGroup);
            app.UR3XYZTab.Title = 'UR3 XYZ';
            app.UR3XYZTab.ButtonDownFcn = createCallbackFcn(app, @UR3XYZTabButtonDown, true);
            app.EditField_UR3Joint1_3 = uieditfield(app.UR3XYZTab, 'numeric');
            app.EditField__UR3Joint2_3 = uieditfield(app.UR3XYZTab, 'numeric');
            app.EditField__UR3Joint3_3 = uieditfield(app.UR3XYZTab, 'numeric');
            app.EditField__UR3Joint4_3 = uieditfield(app.UR3XYZTab, 'numeric');
            app.EditField__UR3Joint6_3 = uieditfield(app.UR3XYZTab, 'numeric');
            app.EditField__UR3Joint5_3 = uieditfield(app.UR3XYZTab, 'numeric');
            app.XTranslationLabel = uilabel(app.UR3XYZTab);
            app.XTranslationLabel.Text = 'X Translation';
            app.Joint1Slider_UR3_3 = uislider(app.UR3XYZTab);
            app.Joint1Slider_UR3_3.Limits = [-1 1];
            app.Joint1Slider_UR3_3.ValueChangedFcn = createCallbackFcn(app, @Joint1Slider_UR3_3ValueChanged, true);
            app.Joint1Slider_UR3_3.ValueChangingFcn = createCallbackFcn(app, @Joint1Slider_UR3_3ValueChanging, true);
            app.YTranslationLabel = uilabel(app.UR3XYZTab);
            app.YTranslationLabel.Text = 'Y Translation';
            app.Joint2Slider_UR3_3 = uislider(app.UR3XYZTab);
            app.Joint2Slider_UR3_3.Limits = [-1 1];
            app.Joint2Slider_UR3_3.ValueChangedFcn = createCallbackFcn(app, @Joint2Slider_UR3_3ValueChanged, true);
            app.Joint2Slider_UR3_3.ValueChangingFcn = createCallbackFcn(app, @Joint2Slider_UR3_3ValueChanging, true);
            app.ZTranslationLabel = uilabel(app.UR3XYZTab);
            app.ZTranslationLabel.Text = 'Z Translation';
            app.Joint3Slider_UR3_3 = uislider(app.UR3XYZTab);
            app.Joint3Slider_UR3_3.Limits = [-1 1];
            app.Joint3Slider_UR3_3.ValueChangedFcn = createCallbackFcn(app, @Joint3Slider_UR3_3ValueChanged, true);
            app.Joint3Slider_UR3_3.ValueChangingFcn = createCallbackFcn(app, @Joint3Slider_UR3_3ValueChanging, true);
            app.YRotationLabel = uilabel(app.UR3XYZTab);
            app.YRotationLabel.Text = 'Y Rotation';
            app.Joint5Slider_UR3_3 = uislider(app.UR3XYZTab);
            app.Joint5Slider_UR3_3.Limits = [-1 1];
            app.Joint5Slider_UR3_3.ValueChangedFcn = createCallbackFcn(app, @Joint5Slider_UR3_3ValueChanged, true);
            app.Joint5Slider_UR3_3.ValueChangingFcn = createCallbackFcn(app, @Joint5Slider_UR3_3ValueChanging, true);
            app.XRotationLabel = uilabel(app.UR3XYZTab);
            app.XRotationLabel.Text = 'X Rotation';
            app.Joint4Slider_UR3_3 = uislider(app.UR3XYZTab);
            app.Joint4Slider_UR3_3.Limits = [-1 1];
            app.Joint4Slider_UR3_3.ValueChangedFcn = createCallbackFcn(app, @Joint4Slider_UR3_3ValueChanged, true);
            app.Joint4Slider_UR3_3.ValueChangingFcn = createCallbackFcn(app, @Joint4Slider_UR3_3ValueChanging, true);
            app.ZRotationLabel = uilabel(app.UR3XYZTab);
            app.ZRotationLabel.Text = 'Z Rotation';
            app.Joint6Slider_UR3_3 = uislider(app.UR3XYZTab);
            app.Joint6Slider_UR3_3.Limits = [-1 1];
            app.Joint6Slider_UR3_3.ValueChangedFcn = createCallbackFcn(app, @Joint6Slider_UR3_3ValueChanged, true);
            app.Joint6Slider_UR3_3.ValueChangingFcn = createCallbackFcn(app, @Joint6Slider_UR3_3ValueChanging, true);
            app.CustomBotjointTab = uitab(app.TabGroup);
            app.CustomBotjointTab.Title = 'CustomBot Teach';
            app.CustomBotjointTab.ButtonDownFcn = createCallbackFcn(app, @CustomBotjointTabButtonDown, true);
            app.Joint1SliderLabel = uilabel(app.CustomBotjointTab);
            app.Joint1SliderLabel.Text = 'Joint 1';
            app.Joint2SliderLabel = uilabel(app.CustomBotjointTab);
            app.Joint2SliderLabel.Text = 'Joint 2';
            app.Joint3SliderLabel = uilabel(app.CustomBotjointTab);
            app.Joint3SliderLabel.Text = 'Joint 3';
            app.Joint5SliderLabel = uilabel(app.CustomBotjointTab);
            app.Joint5SliderLabel.Text = 'Joint 5';
            app.Joint4SliderLabel = uilabel(app.CustomBotjointTab);
            app.Joint4SliderLabel.Text = 'Joint 4';
            app.Joint6SliderLabel = uilabel(app.CustomBotjointTab);
            app.Joint6SliderLabel.Text = 'Joint 6';
            app.Joint7SliderLabel = uilabel(app.CustomBotjointTab);
            app.Joint7SliderLabel.Text = 'Joint 7';
            app.Joint7Slider_LabBot = uislider(app.CustomBotjointTab);
            app.Joint7Slider_LabBot.Limits = [-360 360];
            app.Joint7Slider_LabBot.ValueChangingFcn = createCallbackFcn(app, @Joint7Slider_LabBotValueChanging, true);
            app.Joint7Slider_LabBot.Value = 0.01;
            app.EditField__LabBotJoint7 = uieditfield(app.CustomBotjointTab, 'numeric');
            app.Joint6Slider_LabBot = uislider(app.CustomBotjointTab);
            app.Joint6Slider_LabBot.Limits = [-360 360];
            app.Joint6Slider_LabBot.ValueChangingFcn = createCallbackFcn(app, @Joint6Slider_LabBotValueChanging, true);
            app.EditField__LabBotJoint6 = uieditfield(app.CustomBotjointTab, 'numeric');
            app.Joint5Slider_LabBot = uislider(app.CustomBotjointTab);
            app.Joint5Slider_LabBot.Limits = [-360 360];
            app.Joint5Slider_LabBot.ValueChangingFcn = createCallbackFcn(app, @Joint5Slider_LabBotValueChanging, true);
            app.EditField__LabBotJoint5 = uieditfield(app.CustomBotjointTab, 'numeric');
            app.Joint4Slider_LabBot = uislider(app.CustomBotjointTab);
            app.Joint4Slider_LabBot.Limits = [-360 360];
            app.Joint4Slider_LabBot.ValueChangingFcn = createCallbackFcn(app, @Joint4Slider_LabBotValueChanging, true);
            app.EditField__LabBotJoint4 = uieditfield(app.CustomBotjointTab, 'numeric');
            app.Joint3Slider_LabBot = uislider(app.CustomBotjointTab);
            app.Joint3Slider_LabBot.Limits = [-360 360];
            app.Joint3Slider_LabBot.ValueChangingFcn = createCallbackFcn(app, @Joint3Slider_LabBotValueChanging, true);
            app.EditField__LabBotJoint3 = uieditfield(app.CustomBotjointTab, 'numeric');
            app.Joint2Slider_LabBot = uislider(app.CustomBotjointTab);
            app.Joint2Slider_LabBot.Limits = [-360 360];
            app.Joint2Slider_LabBot.ValueChangingFcn = createCallbackFcn(app, @Joint2Slider_LabBotValueChanging, true);
            app.EditField__LabBotJoint2 = uieditfield(app.CustomBotjointTab, 'numeric');
            app.Joint1Slider_LabBot = uislider(app.CustomBotjointTab);
            app.Joint1Slider_LabBot.Limits = [0.1 0.8];
            app.Joint1Slider_LabBot.ValueChangingFcn = createCallbackFcn(app, @Joint1Slider_LabBotValueChanging, true);
            app.Joint1Slider_LabBot.Value = 0.1;
            app.EditField_LabBotJoint1 = uieditfield(app.CustomBotjointTab, 'numeric');
            app.CustomBotXYZTab = uitab(app.TabGroup);
            app.CustomBotXYZTab.Title = 'CustomBot XYZ';
            app.CustomBotXYZTab.ButtonDownFcn = createCallbackFcn(app, @CustomBotXYZTabButtonDown, true);
            app.EditField_UR3Joint1_4 = uieditfield(app.CustomBotXYZTab, 'numeric');
            app.EditField__UR3Joint2_4 = uieditfield(app.CustomBotXYZTab, 'numeric');
            app.EditField__UR3Joint3_4 = uieditfield(app.CustomBotXYZTab, 'numeric');
            app.EditField__UR3Joint4_4 = uieditfield(app.CustomBotXYZTab, 'numeric');
            app.EditField__UR3Joint6_4 = uieditfield(app.CustomBotXYZTab, 'numeric');
            app.EditField__UR3Joint5_4 = uieditfield(app.CustomBotXYZTab, 'numeric');
            app.XTranslationLabel_2 = uilabel(app.CustomBotXYZTab);
            app.XTranslationLabel_2.Text = 'X Translation';
            app.Joint1Slider_UR3_4 = uislider(app.CustomBotXYZTab);
            app.Joint1Slider_UR3_4.Limits = [-1 1];
            app.Joint1Slider_UR3_4.ValueChangingFcn = createCallbackFcn(app, @Joint1Slider_UR3_4ValueChanging, true);
            app.YTranslationLabel_2 = uilabel(app.CustomBotXYZTab);
            app.YTranslationLabel_2.Text = 'Y Translation';
            app.Joint2Slider_UR3_4 = uislider(app.CustomBotXYZTab);
            app.Joint2Slider_UR3_4.Limits = [-1 1];
            app.Joint2Slider_UR3_4.ValueChangingFcn = createCallbackFcn(app, @Joint2Slider_UR3_4ValueChanging, true);
            app.ZTranslationLabel_2 = uilabel(app.CustomBotXYZTab);
            app.ZTranslationLabel_2.Text = 'Z Translation';
            app.Joint3Slider_UR3_4 = uislider(app.CustomBotXYZTab);
            app.Joint3Slider_UR3_4.Limits = [-1 1];
            app.Joint3Slider_UR3_4.ValueChangingFcn = createCallbackFcn(app, @Joint3Slider_UR3_4ValueChanging, true);
            app.YRotationLabel_2 = uilabel(app.CustomBotXYZTab);
            app.YRotationLabel_2.Text = 'Y Rotation';
            app.Joint5Slider_UR3_4 = uislider(app.CustomBotXYZTab);
            app.Joint5Slider_UR3_4.Limits = [-1 1];
            app.Joint5Slider_UR3_4.ValueChangingFcn = createCallbackFcn(app, @Joint5Slider_UR3_4ValueChanging, true);
            app.XRotationLabel_2 = uilabel(app.CustomBotXYZTab);
            app.XRotationLabel_2.Text = 'X Rotation';
            app.Joint4Slider_UR3_4 = uislider(app.CustomBotXYZTab);
            app.Joint4Slider_UR3_4.Limits = [-1 1];
            app.Joint4Slider_UR3_4.ValueChangingFcn = createCallbackFcn(app, @Joint4Slider_UR3_4ValueChanging, true);
            app.ZRotationLabel_2 = uilabel(app.CustomBotXYZTab);
            app.ZRotationLabel_2.Text = 'Z Rotation';
            app.Joint6Slider_UR3_4 = uislider(app.CustomBotXYZTab);
            app.Joint6Slider_UR3_4.Limits = [-1 1];
            app.Joint6Slider_UR3_4.ValueChangingFcn = createCallbackFcn(app, @Joint6Slider_UR3_4ValueChanging, true);
            app.Button_2 = uibutton(app.UIFigure, 'push');
            app.Button_2.ButtonPushedFcn = createCallbackFcn(app, @Button_2Pushed, true);
            app.Button_2.Tag = 'E-Stop';
            app.Button_2.Icon = fullfile(pathToMLAPP, 'E-Stop.png');
            app.Button_2.Text = '';
            app.DemonstrationMixChemButton = uibutton(app.UIFigure, 'push');
            app.DemonstrationMixChemButton.ButtonPushedFcn = createCallbackFcn(app, @DemonstrationMixChemButtonPushed, true);
            app.DemonstrationMixChemButton.Text = 'Demonstration Mix Chem';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = GUI

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end