classdef TestTeachXYZ < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        TeachPendant                   matlab.ui.Figure
        GridLayout                     matlab.ui.container.GridLayout
        LeftPanel                      matlab.ui.container.Panel
        Panel                          matlab.ui.container.Panel
        GridLayout2                    matlab.ui.container.GridLayout
        TranslationGainEditField       matlab.ui.control.NumericEditField
        TranslationGainEditFieldLabel  matlab.ui.control.Label
        VelocityGainEditFieldLabel_4   matlab.ui.control.Label
        VelocityGainEditFieldLabel_3   matlab.ui.control.Label
        VelocityGainEditFieldLabel_2   matlab.ui.control.Label
        Slider_3                       matlab.ui.control.Slider
        Slider_3Label                  matlab.ui.control.Label
        Slider_2                       matlab.ui.control.Slider
        Slider_2Label                  matlab.ui.control.Label
        Slider                         matlab.ui.control.Slider
        SliderLabel                    matlab.ui.control.Label
        RightPanel                     matlab.ui.container.Panel
        GridLayout3                    matlab.ui.container.GridLayout
        VelocityGainEditFieldLabel_5   matlab.ui.control.Label
        VelocityGainEditFieldLabel_6   matlab.ui.control.Label
        VelocityGainEditFieldLabel_7   matlab.ui.control.Label
        Slider_4                       matlab.ui.control.Slider
        Slider_4Label                  matlab.ui.control.Label
        Slider_5                       matlab.ui.control.Slider
        Slider_5Label                  matlab.ui.control.Label
        Slider_6                       matlab.ui.control.Slider
        Slider_6Label                  matlab.ui.control.Label
        RotationGainEditField          matlab.ui.control.NumericEditField
        RotationGainEditFieldLabel     matlab.ui.control.Label
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
    end


    properties (Access = private)
        autoUpdate
        fx
        fy
        fz
        tx
        ty
        tz
        myTimer % Description
        
    end
    
    methods (Access = private)
        
        function updateJoy(app,~,~)
            wrench = zeros(6,1);
            wrench(1) = app.fx * app.TranslationGainEditField.Value;
            wrench(2) = app.fy * app.TranslationGainEditField.Value;
            wrench(3) = app.fz * app.TranslationGainEditField.Value;
            wrench(4) = app.tx * app.RotationGainEditField.Value;
            wrench(5) = app.ty * app.RotationGainEditField.Value;
            wrench(6) = app.tz * app.RotationGainEditField.Value;

            assignin('base','wrench',wrench);
            %evalin('base','who');
            % wrench = [app.vx; app.vy; app.vz; app.wx; app.wy; app.wz];
            %disp(wrench);
        end


    end
    
    methods (Access = public)
        
        function wrench = read(app)
            wrench = zeros(6,1);
            wrench(1) = app.fx * app.TranslationGainEditField.Value;
            wrench(2) = app.fy * app.TranslationGainEditField.Value;
            wrench(3) = app.fz * app.TranslationGainEditField.Value;
            wrench(4) = app.tx * app.RotationGainEditField.Value;
            wrench(5) = app.ty * app.RotationGainEditField.Value;
            wrench(6) = app.tz * app.RotationGainEditField.Value;
        end


    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
          
            app.fx = 0;
            app.fy = 0;
            app.fz = 0;
            app.tx = 0;
            app.ty = 0;
            app.tz = 0;
             
            app.myTimer = timer('Period',0.05,...
                'ExecutionMode', 'fixedSpacing', ...
                'TasksToExecute', Inf);

            app.myTimer.TimerFcn = @(~,~)app.updateJoy;
            
            % start(app.myTimer);
            
            
        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.TeachPendant.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 2x1 grid
                app.GridLayout.RowHeight = {490, 490};
                app.GridLayout.ColumnWidth = {'1x'};
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 1;
            else
                % Change to a 1x2 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {510, '1x'};
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 2;
            end
        end

        % Callback function
        function PlotButtonPushed(app, event)
            updatePlot(app)
        end

        % Value changed function: TranslationGainEditField
        function KnobValueChanged(app, event)
            if app.autoUpdate
                updatePlot(app)
            end
        end

        % Value changing function: Slider
        function SliderValueChanging(app, event)
            app.fx = event.Value;
        end

        % Value changing function: Slider_2
        function Slider_2ValueChanging(app, event)
            app.fy = event.Value;           
        end

        % Value changing function: Slider_3
        function Slider_3ValueChanging(app, event)
            app.fz = event.Value;           
        end

        % Value changing function: Slider_4
        function Slider_4ValueChanging(app, event)
            app.tx = event.Value;
        end

        % Value changing function: Slider_5
        function Slider_5ValueChanging(app, event)
            app.ty = event.Value;
        end

        % Value changing function: Slider_6
        function Slider_6ValueChanging(app, event)
            app.tz = event.Value;          
        end

        % Value changed function: Slider
        function SliderValueChanged(app, event)
            app.Slider.Value = 0; % reset back to zero
            app.fx = 0;
        end

        % Value changed function: Slider_2
        function Slider_2ValueChanged(app, event)
            app.Slider.Value = 0; % reset back to zero
            app.fy = 0;
        end

        % Value changed function: Slider_3
        function Slider_3ValueChanged(app, event)
            app.Slider.Value = 0; % reset back to zero
            app.fz = 0;            
        end

        % Value changed function: Slider_4
        function Slider_4ValueChanged(app, event)
            app.Slider.Value = 0; % reset back to zero
            app.tx = 0;
        end

        % Value changed function: Slider_5
        function Slider_5ValueChanged(app, event)
            app.Slider.Value = 0; % reset back to zero
            app.ty = 0;
        end

        % Value changed function: Slider_6
        function Slider_6ValueChanged(app, event)
            app.Slider.Value = 0; % reset back to zero
            app.tz = 0;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.TeachPendant = uifigure('Visible', 'off');
            app.TeachPendant.Name = 'Teach Pendant';
            app.TeachPendant.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.GridLayout = uigridlayout(app.TeachPendant);
            app.LeftPanel = uipanel(app.GridLayout);
            app.Panel = uipanel(app.LeftPanel);
            app.GridLayout2 = uigridlayout(app.Panel);
            app.SliderLabel = uilabel(app.GridLayout2);
            app.SliderLabel.Text = 'Slider';
            app.Slider = uislider(app.GridLayout2);
            app.Slider.Limits = [-1 1];
            app.Slider.ValueChangedFcn = createCallbackFcn(app, @SliderValueChanged, true);
            app.Slider.ValueChangingFcn = createCallbackFcn(app, @SliderValueChanging, true);
            app.Slider_2Label = uilabel(app.GridLayout2);
            app.Slider_2Label.Text = 'Slider';
            app.Slider_2 = uislider(app.GridLayout2);
            app.Slider_2.Limits = [-1 1];
            app.Slider_2.ValueChangedFcn = createCallbackFcn(app, @Slider_2ValueChanged, true);
            app.Slider_2.ValueChangingFcn = createCallbackFcn(app, @Slider_2ValueChanging, true);
            app.Slider_3Label = uilabel(app.GridLayout2);
            app.Slider_3Label.Text = 'Slider';
            app.Slider_3 = uislider(app.GridLayout2);
            app.Slider_3.Limits = [-1 1];
            app.Slider_3.ValueChangedFcn = createCallbackFcn(app, @Slider_3ValueChanged, true);
            app.Slider_3.ValueChangingFcn = createCallbackFcn(app, @Slider_3ValueChanging, true);
            app.VelocityGainEditFieldLabel_2 = uilabel(app.GridLayout2);
            app.VelocityGainEditFieldLabel_2.Text = 'X-translation';
            app.VelocityGainEditFieldLabel_3 = uilabel(app.GridLayout2);
            app.VelocityGainEditFieldLabel_3.Text = 'Y-translation';
            app.VelocityGainEditFieldLabel_4 = uilabel(app.GridLayout2);
            app.VelocityGainEditFieldLabel_4.Text = 'Z-translation';
            app.TranslationGainEditFieldLabel = uilabel(app.GridLayout2);
            app.TranslationGainEditFieldLabel.Text = 'Translation Gain';
            app.TranslationGainEditField = uieditfield(app.GridLayout2, 'numeric');
            app.TranslationGainEditField.Limits = [0 2];
            app.TranslationGainEditField.ValueChangedFcn = createCallbackFcn(app, @KnobValueChanged, true);
            app.TranslationGainEditField.Value = 1;
            app.RightPanel = uipanel(app.GridLayout);
            app.GridLayout3 = uigridlayout(app.RightPanel);
            app.RotationGainEditFieldLabel = uilabel(app.GridLayout3);
            app.RotationGainEditFieldLabel.Text = 'Rotation Gain';
            app.RotationGainEditField = uieditfield(app.GridLayout3, 'numeric');
            app.RotationGainEditField.Limits = [0 5];
            app.RotationGainEditField.Value = 1;
            app.Slider_6Label = uilabel(app.GridLayout3);
            app.Slider_6Label.Text = 'Slider';
            app.Slider_6 = uislider(app.GridLayout3);
            app.Slider_6.Limits = [-1 1];
            app.Slider_6.ValueChangedFcn = createCallbackFcn(app, @Slider_6ValueChanged, true);
            app.Slider_6.ValueChangingFcn = createCallbackFcn(app, @Slider_6ValueChanging, true);
            app.Slider_5Label = uilabel(app.GridLayout3);
            app.Slider_5Label.Text = 'Slider';
            app.Slider_5 = uislider(app.GridLayout3);
            app.Slider_5.Limits = [-1 1];
            app.Slider_5.ValueChangedFcn = createCallbackFcn(app, @Slider_5ValueChanged, true);
            app.Slider_5.ValueChangingFcn = createCallbackFcn(app, @Slider_5ValueChanging, true);
            app.Slider_4Label = uilabel(app.GridLayout3);
            app.Slider_4Label.Text = 'Slider';
            app.Slider_4 = uislider(app.GridLayout3);
            app.Slider_4.Limits = [-1 1];
            app.Slider_4.ValueChangedFcn = createCallbackFcn(app, @Slider_4ValueChanged, true);
            app.Slider_4.ValueChangingFcn = createCallbackFcn(app, @Slider_4ValueChanging, true);
            app.VelocityGainEditFieldLabel_7 = uilabel(app.GridLayout3);
            app.VelocityGainEditFieldLabel_7.Text = 'Z-rotation';
            app.VelocityGainEditFieldLabel_6 = uilabel(app.GridLayout3);
            app.VelocityGainEditFieldLabel_6.Text = 'Y-rotation';
            app.VelocityGainEditFieldLabel_5 = uilabel(app.GridLayout3);
            app.VelocityGainEditFieldLabel_5.Text = 'X-rotation';
            % Show the figure after all components are created
            app.TeachPendant.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = TestTeachXYZ

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.TeachPendant)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.TeachPendant)
        end
    end
end